classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Panel_Absolute      matlab.ui.container.Panel
        AbsoRamaButton      matlab.ui.control.Button
        Panel_SquareRoot    matlab.ui.container.Panel
        SqaredRamaButton    matlab.ui.control.Button
        Panel_Square        matlab.ui.container.Panel
        SquareRamaButton    matlab.ui.control.Button
        Panel_Product       matlab.ui.container.Panel
        ProduceaRamaButton  matlab.ui.control.Button
        Panel_Sum           matlab.ui.container.Panel
        SummeramaButton     matlab.ui.control.Button
        Panel_textbox       matlab.ui.container.Panel
        TextArea            matlab.ui.control.TextArea
        Panel_character     matlab.ui.container.Panel
        Image               matlab.ui.control.Image
        UIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
    % for multi-line dialogues
    DialogueLines       % cell array of dialogue strings
    CurrentLineIdx      % index of current line
    IsTyping            % true if typewriter effect is running
    TypeTimer           % handle to the active typewriter timer
    AutoAdvanceTimer    % handle for timers that auto-advance dialogue
end

methods (Access = public)
    % Startup function
    function StartupFcn(app)
        disableDefaultInteractivity(app.UIAxes);
        app.UIAxes.XTick = [];
        app.UIAxes.YTick = [];
        app.UIAxes.XColor = 'none';
        app.UIAxes.YColor = 'none';
        app.UIAxes.GridColor = [0.2863 0.8588 0.251];
        app.UIAxes.XGrid = 'on';
        app.UIAxes.YGrid = 'on';

        % Set up KeyPress callback
        app.UIFigure.KeyPressFcn = @(src,event) UIFigureKeyPress(app, event);
        app.TextArea.FontColor = [1 1 1];

        % Load intro dialogue (full cell array)
        app.DialogueLines = dialogue('intro');
        app.CurrentLineIdx = 1;
        app.showLine();
    end         
end

methods (Access = private)
    % Typewriter animation - now keeps existing text
    function typewrite(app, msg)
        % Convert msg to char if it's a string
        if isstring(msg)
            msg = char(msg);
        end
        
        % If already typing → finish instantly
        if app.IsTyping
            if ~isempty(app.TypeTimer) && isvalid(app.TypeTimer)
                stop(app.TypeTimer);
                delete(app.TypeTimer);
            end
            app.TypeTimer = [];
            
            % Add the complete message to existing content
            currentContent = app.TextArea.Value;
            if isempty(currentContent)
                app.TextArea.Value = {msg};
            else
                % Add new line to existing content
                currentContent{end+1} = msg;
                app.TextArea.Value = currentContent;
            end
            app.IsTyping = false;
            return
        end

        % Get existing content
        if isempty(app.TextArea.Value)
            previousLines = {};
        else
            previousLines = app.TextArea.Value;
        end

        % Start typing the new message
        app.IsTyping = true;
        str = '';
        k = 1;

        % Clear any previous timer
        if ~isempty(app.TypeTimer) && isvalid(app.TypeTimer)
            stop(app.TypeTimer);
            delete(app.TypeTimer);
        end

        app.TypeTimer = timer('ExecutionMode','fixedSpacing','Period',0.03, ...
            'TasksToExecute',length(msg), ...
            'TimerFcn', @(~,~) appendChar());

        function appendChar()
            str = [str, msg(k)];
            % Update TextArea with previous lines + current typing line
            newContent = [previousLines; {str}];
            app.TextArea.Value = newContent;
            k = k + 1;
            if k > length(msg)
                stop(app.TypeTimer);
                delete(app.TypeTimer);
                app.TypeTimer = [];
                app.IsTyping = false;
            end
        end

        start(app.TypeTimer);
    end

    % Show current line from DialogueLines
    function showLine(app)
        if app.CurrentLineIdx > numel(app.DialogueLines)
            % End of dialogue
            app.DialogueLines = [];
            app.CurrentLineIdx = -1;
            
            % Add end message to existing content
            currentContent = app.TextArea.Value;
            if isempty(currentContent)
                app.TextArea.Value = {'* Dialogue finished. Press any key to continue...'};
            else
                currentContent{end+1} = '* Dialogue finished. Press any key to continue...';
                app.TextArea.Value = currentContent;
            end
            return;
        end

        % Show current line with typewriter (will be added to existing content)
        line = app.DialogueLines{app.CurrentLineIdx};
        app.typewrite(line);

        % Auto-advance after pause
        if app.CurrentLineIdx < numel(app.DialogueLines)
            pauseTime = 0.03 * length(line) + 1.0;
            app.AutoAdvanceTimer = timer('StartDelay', pauseTime, ...
                                 'TimerFcn', @(~,~) goNext());
            start(app.AutoAdvanceTimer);
        end

        function goNext()
        % Stop and delete timer safely
            if ~isempty(app.AutoAdvanceTimer) && isvalid(app.AutoAdvanceTimer)
                stop(app.AutoAdvanceTimer);
                delete(app.AutoAdvanceTimer);
                app.AutoAdvanceTimer = [];
            end
        app.CurrentLineIdx = app.CurrentLineIdx + 1;
        app.showLine();

        end

        function UIFigureKeyPress(app, event)
            if isempty(app.DialogueLines)
                return;
            end

            if app.IsTyping
                % Skip typewriter and show whole line instantly
                app.typewrite(app.DialogueLines{app.CurrentLineIdx});
            else
                % Advance to next line
                app.CurrentLineIdx = app.CurrentLineIdx + 1;
                if app.CurrentLineIdx <= numel(app.DialogueLines)
                    app.showLine();
                else
                    app.DialogueLines = [];
                
                    % Add end message to existing content
                    currentContent = app.TextArea.Value;
                    if isempty(currentContent)
                        app.TextArea.Value = {'... [End of dialogue]'};
                    else
                        currentContent{end+1} = '... [End of dialogue]';
                        app.TextArea.Value = currentContent;
                    end
                end
            end
        end
    end
end

    methods (Access = private)
        function resetTextArea(app)
        % Stop typewriter timer
        if ~isempty(app.TypeTimer) && isvalid(app.TypeTimer)
            stop(app.TypeTimer);
            delete(app.TypeTimer);
        end
        app.TypeTimer = [];

        % Stop auto-advance timer
        if ~isempty(app.AutoAdvanceTimer) && isvalid(app.AutoAdvanceTimer)
            stop(app.AutoAdvanceTimer);
            delete(app.AutoAdvanceTimer);
        end
        app.AutoAdvanceTimer = [];

        % Clear TextArea
        app.TextArea.Value = cell(0,1);  % empty N×1 cell array
        app.IsTyping = false;

        % Reset dialogue
        app.CurrentLineIdx = 1;
        app.DialogueLines = {};
        end
    end

methods (Access = private)
    function displayMatrixStats(app, statType)
        % Stop any ongoing dialogue
        app.resetTextArea();

        % Generate matrix and stats
        [matrixA, stats] = main();

        % Format matrix as string
        matrixStr = newline;  % start with a newline
        for i = 1:size(matrixA,1)
            matrixStr = [matrixStr, sprintf('%2d %2d %2d %2d %2d', matrixA(i,:)), newline];
        end
        matrixStr = [matrixStr, newline]; % newline after matrix

        % Decide which stat to show
        switch lower(statType)
            case 'sum'
                statStr = sprintf('* The Sum of All Elements Are: %d', stats.Sum);
            case 'product'
                statStr = sprintf('* The Product of All Elements Are: %d', stats.Product);
            case 'square'
                % stats.Square is a matrix
                squareStr = newline;
                [nRows, ~] = size(stats.Square);
                for i = 1:nRows
                    rowStr = sprintf('%4d', stats.Square(i,:));      % format each row
                    rowStr = strtrim(regexprep(rowStr, '\s+', ' ')); % clean extra spaces
                    squareStr = [squareStr, rowStr, newline];
                end
                squareStr = [squareStr, newline]; 
                statStr = ['* The Square of the Matrix Is:', squareStr];
            case 'square_root'
                squareRootStr = newline;
                [nRows, ~] = size(stats.Sqrt);
                for i = 1:nRows
                    rowStr = sprintf('%6.2f ', stats.Sqrt(i,:));        % format each row
                    rowStr = strtrim(regexprep(rowStr, '\s+', ' '));    % clean extra spaces
                    squareRootStr = [squareRootStr, rowStr, newline];
                end
                squareRootStr = [squareRootStr, newline]
                statStr = ['* The Square Root of the Matrix Is:', squareRootStr];
            case 'absolute'
                absStr = newline;
                [nRows, ~] = size(stats.Square);
                for i = 1:nRows
                    rowStr = sprintf('%4d', stats.Abs(i,:));      % format each row
                    rowStr = strtrim(regexprep(rowStr, '\s+', ' ')); % clean extra spaces
                    absStr = [absStr, rowStr, newline];
                end
                absStr = [absStr, newline]; 
                statStr = ['* The Absolute Value of the Matrix Is:', absStr];
            otherwise
                error('Unknown stat type: %s', statType);
        end

        % Build full dialogue
        fullDialogue = ['* Here is the 5x5 matrix:', matrixStr, statStr];

        % Split into lines for typewriter
        app.DialogueLines = strsplit(fullDialogue, newline)';

        % Reset index & start displaying
        app.CurrentLineIdx = 1;
        app.showLine();
    end
end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SummeramaButton
        function sumButton(app, event)
            app.displayMatrixStats('sum');

        end

        % Button pushed function: ProduceaRamaButton
        function ProduceaRamaButtonPushed(app, event)
            app.displayMatrixStats('product');
        end

        % Button pushed function: SquareRamaButton
        function SquareRamaButtonPushed(app, event)
            app.displayMatrixStats('square');
        end

        % Button pushed function: SqaredRamaButton
        function SqaredRamaButtonPushed(app, event)
            app.displayMatrixStats('square_root');
        end

        % Button pushed function: AbsoRamaButton
        function AbsoRamaButtonPushed(app, event)
            app.displayMatrixStats('absolute');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel_character = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.Panel_character);
            zlabel(app.UIAxes, 'Z')
            app.Image = uiimage(app.Panel_character);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'annoying-dog-undertale.gif');
            app.Panel_textbox = uipanel(app.UIFigure);
            app.TextArea = uitextarea(app.Panel_textbox);
            app.Panel_Sum = uipanel(app.UIFigure);
            app.SummeramaButton = uibutton(app.Panel_Sum, 'push');
            app.SummeramaButton.ButtonPushedFcn = createCallbackFcn(app, @sumButton, true);
            app.SummeramaButton.Interpreter = 'html';
            app.SummeramaButton.Text = 'Sum me rama';
            app.Panel_Product = uipanel(app.UIFigure);
            app.ProduceaRamaButton = uibutton(app.Panel_Product, 'push');
            app.ProduceaRamaButton.ButtonPushedFcn = createCallbackFcn(app, @ProduceaRamaButtonPushed, true);
            app.ProduceaRamaButton.Interpreter = 'html';
            app.ProduceaRamaButton.Text = 'Produce a Rama';
            app.Panel_Square = uipanel(app.UIFigure);
            app.SquareRamaButton = uibutton(app.Panel_Square, 'push');
            app.SquareRamaButton.ButtonPushedFcn = createCallbackFcn(app, @SquareRamaButtonPushed, true);
            app.SquareRamaButton.Interpreter = 'html';
            app.SquareRamaButton.Text = 'Square Rama?';
            app.Panel_SquareRoot = uipanel(app.UIFigure);
            app.SqaredRamaButton = uibutton(app.Panel_SquareRoot, 'push');
            app.SqaredRamaButton.ButtonPushedFcn = createCallbackFcn(app, @SqaredRamaButtonPushed, true);
            app.SqaredRamaButton.Interpreter = 'html';
            app.SqaredRamaButton.Text = 'Sqared Rama!';
            app.Panel_Absolute = uipanel(app.UIFigure);
            app.AbsoRamaButton = uibutton(app.Panel_Absolute, 'push');
            app.AbsoRamaButton.ButtonPushedFcn = createCallbackFcn(app, @AbsoRamaButtonPushed, true);
            app.AbsoRamaButton.Interpreter = 'html';
            app.AbsoRamaButton.Text = 'AbsoRama';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end